classdef ecgdisp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhysioNetECGViewerUIFigure  matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        GetDataButton               matlab.ui.control.Button
        DataDropDownLabel           matlab.ui.control.Label
        DataDropDown                matlab.ui.control.DropDown
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_7                    matlab.ui.control.UIAxes
        UIAxes_8                    matlab.ui.control.UIAxes
        UIAxes_9                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_10                   matlab.ui.control.UIAxes
        UIAxes_11                   matlab.ui.control.UIAxes
        UIAxes_12                   matlab.ui.control.UIAxes
        DatabaseDropDownLabel       matlab.ui.control.Label
        DatabaseDropDown            matlab.ui.control.DropDown
        StartTimesEditFieldLabel    matlab.ui.control.Label
        StartTimesEditField         matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel      matlab.ui.control.Label
        EndTimesEditField           matlab.ui.control.NumericEditField
        HiddenAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        databases = ["staffiii";"ptbdb"];
        current_records = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DatabaseDropDown.Items = app.databases;
            app.DatabaseDropDown.ItemsData = 1:size(app.databases);
            app.DatabaseDropDown.Value = 1;
            
            d = uiprogressdlg(app.PhysioNetECGViewerUIFigure,'Title','Please Wait',...
            'Message','Opening the application');
        
            d.Value = 0;
            d.Message = 'Reading database record file...';
            
            % Get the RECORDS file in the selected database, which tells you what data records are available
            [data,status] = urlread(char(strcat("http://physionet.org/physiobank/database/",app.DatabaseDropDown.Items(app.DatabaseDropDown.Value),"/RECORDS")));
            if (status ~= 1)
               uialert(app.PhysioNetECGViewerUIFigure, 'Could not get data from PhysioNet Database! Are you connected to the internet?', 'Error: Could not connect'); 
            end
            
            records = regexp(data,'\n','split');
            app.current_records = records;
            
            records_trim = records;
            if (any(contains(records,'/')))
                records_trim = extractAfter(records,'/');
            end
            app.DataDropDown.Items = records_trim;
            app.DataDropDown.ItemsData = 1:size(records_trim,2);
            app.DataDropDown.Value = 1;
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            d = uiprogressdlg(app.PhysioNetECGViewerUIFigure,'Title','Please Wait',...
            'Message','Starting');
            % Get database used
            current_database = app.DatabaseDropDown.Items(app.DatabaseDropDown.Value);
            % Get record used = app.current_records
            % Calculate start sample and number of samples to take from start and end time fields
            % 1 ms = 1 sample, start = 1 means start from 0 so we need to take (seconds x 1000) + 1
            start_sample = (app.StartTimesEditField.Value * 1000) + 1;
            end_sample = (app.EndTimesEditField.Value * 1000) + 1;
            if (end_sample < start_sample)
                end_sample = start_sample;
                app.EndTimesEditField.Value = app.StartTimesEditField.Value;
            end
            
            d.Value = 0;
            d.Message = 'Getting ECG record...';
            % Get the signals
            [ecg, Fs, tm] = rdsamp(char(strcat(current_database, '/', app.current_records(app.DataDropDown.Value))), [], end_sample, start_sample);
            %for staffiii database it's a 12-lead ECG with signal channels V1-V6, I,
            %II, III
            %AVR, AVL and AVF must be calculated
            %https://pdfs.semanticscholar.org/8160/8b62b6efb007d112b438655dd2c897759fb1.pdf
            %AVR = -1/2 * I - 1/2 * II
            %AVL = I - 1/2 * II
            %AVF = II - 1/2 * I
            avr = -0.5 * ecg(:,7) - 0.5 * ecg(:,8);
            avl = ecg(:,7) - 0.5 * ecg(:,8);
            avf = ecg(:,8) - 0.5 * ecg(:,7);
            
            d.Value = .5; 
            d.Message = 'Plotting ECG data...';
            
            % Update correct UIAxes
            m = plot(app.UIAxes, tm(:), ecg(:,1), 'm');
            plot(app.UIAxes_2, tm(:), ecg(:,2), 'm');
            b = plot(app.UIAxes_3, tm(:), ecg(:,3), 'b');
            plot(app.UIAxes_4, tm(:), ecg(:,4), 'b');
            g = plot(app.UIAxes_5, tm(:), ecg(:,5), 'g');
            plot(app.UIAxes_6, tm(:), ecg(:,6), 'g');
            plot(app.UIAxes_7, tm(:), ecg(:,7), 'g');
            r = plot(app.UIAxes_8, tm(:), ecg(:,8), 'r');
            plot(app.UIAxes_9, tm(:), ecg(:,9), 'r');
            plot(app.UIAxes_10, tm(:), avr, 'k');
            plot(app.UIAxes_11, tm(:), avl, 'g');
            plot(app.UIAxes_12, tm(:), avf, 'r');
            
            legend(app.HiddenAxes,[m b g r], 'Septal', 'Anterior', 'Lateral', 'Inferior');
            d.Value = 1;
            d.Message = 'Finishing...';
            
            close(d);
            
        end

        % Value changed function: DatabaseDropDown
        function DatabaseDropDownValueChanged(app, event)
            value = app.DatabaseDropDown.Value;
            d = uiprogressdlg(app.PhysioNetECGViewerUIFigure,'Title','Please Wait',...
            'Message','Opening the application');
        
            d.Value = 0;
            d.Message = 'Reading database record file...';
            [data,status] = urlread(char(strcat('http://physionet.org/physiobank/database/',app.DatabaseDropDown.Items(app.DatabaseDropDown.Value),'/RECORDS')));
            if (status ~= 1)
               uialert(app.PhysioNetECGViewerUIFigure, 'Could not get data from PhysioNet Database! Are you connected to the internet?', 'Error: Could not connect'); 
            end
            
            records = regexp(data,'\n','split');
            app.current_records = records;
            
            records_trim = records;
            if (any(contains(records,'/')))
                records_trim = extractAfter(records,'/');
            end
            app.DataDropDown.Items = records_trim;
            app.DataDropDown.ItemsData = 1:size(records_trim,2);
            app.DataDropDown.Value = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhysioNetECGViewerUIFigure = uifigure('Visible', 'off');
            app.PhysioNetECGViewerUIFigure.Name = 'PhysioNet ECG Viewer';
            app.UIAxes = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes, 'V1')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'mV')
            app.GetDataButton = uibutton(app.PhysioNetECGViewerUIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.DataDropDownLabel = uilabel(app.PhysioNetECGViewerUIFigure);
            app.DataDropDownLabel.Text = 'Data #';
            app.DataDropDown = uidropdown(app.PhysioNetECGViewerUIFigure);
            app.UIAxes_2 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_2, 'V2')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'mV')
            app.UIAxes_3 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_3, 'V3')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'mV')
            app.UIAxes_4 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_4, 'V4')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'mV')
            app.UIAxes_5 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_5, 'V5')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'mV')
            app.UIAxes_7 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_7, 'I')
            xlabel(app.UIAxes_7, 'Time (s)')
            ylabel(app.UIAxes_7, 'mV')
            app.UIAxes_8 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_8, 'II')
            xlabel(app.UIAxes_8, 'Time (s)')
            ylabel(app.UIAxes_8, 'mV')
            app.UIAxes_9 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_9, 'III')
            xlabel(app.UIAxes_9, 'Time (s)')
            ylabel(app.UIAxes_9, 'mV')
            app.UIAxes_6 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_6, 'V6')
            xlabel(app.UIAxes_6, 'Time (s)')
            ylabel(app.UIAxes_6, 'mV')
            app.UIAxes_10 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_10, 'AVR')
            xlabel(app.UIAxes_10, 'Time (s)')
            ylabel(app.UIAxes_10, 'mV')
            app.UIAxes_11 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_11, 'AVL')
            xlabel(app.UIAxes_11, 'Time (s)')
            ylabel(app.UIAxes_11, 'mV')
            app.UIAxes_12 = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.UIAxes_12, 'AVF')
            xlabel(app.UIAxes_12, 'Time (s)')
            ylabel(app.UIAxes_12, 'mV')
            app.DatabaseDropDownLabel = uilabel(app.PhysioNetECGViewerUIFigure);
            app.DatabaseDropDownLabel.Text = 'Database';
            app.DatabaseDropDown = uidropdown(app.PhysioNetECGViewerUIFigure);
            app.DatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DatabaseDropDownValueChanged, true);
            app.StartTimesEditFieldLabel = uilabel(app.PhysioNetECGViewerUIFigure);
            app.StartTimesEditFieldLabel.Text = 'Start Time (s)';
            app.StartTimesEditField = uieditfield(app.PhysioNetECGViewerUIFigure, 'numeric');
            app.StartTimesEditField.Limits = [0 Inf];
            app.EndTimesEditFieldLabel = uilabel(app.PhysioNetECGViewerUIFigure);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.PhysioNetECGViewerUIFigure, 'numeric');
            app.EndTimesEditField.Limits = [0 Inf];
            app.EndTimesEditField.Value = 10;
            app.HiddenAxes = uiaxes(app.PhysioNetECGViewerUIFigure);
            title(app.HiddenAxes, 'Title')
            xlabel(app.HiddenAxes, 'X')
            ylabel(app.HiddenAxes, 'Y')
            app.HiddenAxes.Visible = 'off';
            % Show the figure after all components are created
            app.PhysioNetECGViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecgdisp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhysioNetECGViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhysioNetECGViewerUIFigure)
        end
    end
end